classdef visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Visualizer                matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        PlayButton                matlab.ui.control.Button
        TransmitterDropDownLabel  matlab.ui.control.Label
        TxDropdown                matlab.ui.control.DropDown
        ReceiverDropDownLabel     matlab.ui.control.Label
        RxDropdown                matlab.ui.control.DropDown
        TimestepSliderLabel       matlab.ui.control.Label
        TimestepSlider            matlab.ui.control.Slider
        PauseButton               matlab.ui.control.Button
        TimestepSpinner           matlab.ui.control.Spinner
        AodAxes                   matlab.ui.control.UIAxes
        AoaAxes                   matlab.ui.control.UIAxes
        BrowseButton              matlab.ui.control.Button
        SelectScenarioLabel       matlab.ui.control.Label
        ReflectionOrderLabel      matlab.ui.control.Label
        RefOrderDropdown          matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        scenarioName % name of the selected scenario
        
        timestepInfo
        
        outputPath
        visualizerPath
        ns3Path
        
        numNodes
        numPaas
        txIndex % starting from 1
        rxIndex % starting from 1

        
        roomPlotHandle
        raysPlotHandle
        nodesPlotHandle
        paasFrontTxPlotHandle
        paasBackTxPlotHandle
        paasFrontRxPlotHandle
        paasBackRxPlotHandle

        aoaPlotHandle
        aodPlotHandle
        
        framerate = 20;
        playFlag = false;
        
        powerLim
        
        srcPath % absolute path to src/ folder
    end
    
    properties (SetObservable)
          currentTimestep
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % make app robust to different working folders
            currentPath = cd(fileparts(mfilename('fullpath'))); % cd to app's folder
            
            addpath('.', 'raysAppFunctions', '../utils/', '../raytracer/', '../utils/quaternions/') % add important paths relative to app's folder
            
            
            cd('..') % cd to src/
            app.srcPath = pwd; % save path to src/ folder
            cd(currentPath) % fall back to original folder
            
            % Init
            app.Visualizer.Name = 'Visualizer';
            app.AoaAxes.Title.String = 'AoA';
            app.AodAxes.Title.String = 'AoD';
            setupAoaAodPlots(app)
            
            % Properties setup
            app.TimestepSpinner.RoundFractionalValues = 'on';
            
            addlistener(app,'currentTimestep','PostSet',@updateTimestep);
            
            % Load default scenario
            updateScenario(app, fullfile(app.srcPath, 'examples/BoxLectureRoom'))
        end

        % Callback function
        function ScenarioDropdownValueChanged(app, event)
            updateScenario(app)
            raysAppPlotRoom(app)            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.playFlag = true;
            playScenario(app, event)
        end

        % Value changed function: TxDropdown
        function TxDropdownValueChanged(app, event)
            value = app.TxDropdown.Value;
            setupTxSlider(app, str2double(value)+1)
            plotFrame(app)
        end

        % Value changed function: RxDropdown
        function RxDropdownValueChanged(app, event)
            value = app.RxDropdown.Value;
            setupRxSlider(app, str2double(value)+1)
            plotFrame(app)
        end

        % Value changed function: TimestepSlider
        function TimestepSliderValueChanged(app, event)
            value = app.TimestepSlider.Value;
            
        end

        % Callback function
        function TimeStepFieldValueChanged(app, event)
            
        end

        % Value changing function: TimestepSlider
        function TimestepSliderValueChanging(app, event)
            app.playFlag = false;
            roundedValue = round(event.Value);
            
            if roundedValue ~= app.currentTimestep
                app.currentTimestep = roundedValue;
            end
        end

        % Value changed function: TimestepSpinner
        function TimestepSpinnerValueChanged(app, event)
            value = app.TimestepSpinner.Value;
            
        end

        % Value changing function: TimestepSpinner
        function TimestepSpinnerValueChanging(app, event)
            app.playFlag = false;
            changingValue = event.Value;
            app.currentTimestep = changingValue;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.playFlag = false;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            updateScenario(app)
        end

        % Value changed function: RefOrderDropdown
        function RefOrderDropdownValueChanged(app, event)
            plotFrame(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Visualizer = uifigure('Visible', 'off');
            app.Visualizer.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.Visualizer);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PlayButton = uibutton(app.Visualizer, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.TransmitterDropDownLabel = uilabel(app.Visualizer);
            app.TransmitterDropDownLabel.Text = 'Transmitter';
            app.TxDropdown = uidropdown(app.Visualizer);
            app.TxDropdown.ValueChangedFcn = createCallbackFcn(app, @TxDropdownValueChanged, true);
            app.ReceiverDropDownLabel = uilabel(app.Visualizer);
            app.ReceiverDropDownLabel.Text = 'Receiver';
            app.RxDropdown = uidropdown(app.Visualizer);
            app.RxDropdown.ValueChangedFcn = createCallbackFcn(app, @RxDropdownValueChanged, true);
            app.TimestepSliderLabel = uilabel(app.Visualizer);
            app.TimestepSliderLabel.Text = 'Time-step';
            app.TimestepSlider = uislider(app.Visualizer);
            app.TimestepSlider.ValueChangedFcn = createCallbackFcn(app, @TimestepSliderValueChanged, true);
            app.TimestepSlider.ValueChangingFcn = createCallbackFcn(app, @TimestepSliderValueChanging, true);
            app.PauseButton = uibutton(app.Visualizer, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.TimestepSpinner = uispinner(app.Visualizer);
            app.TimestepSpinner.ValueChangingFcn = createCallbackFcn(app, @TimestepSpinnerValueChanging, true);
            app.TimestepSpinner.ValueChangedFcn = createCallbackFcn(app, @TimestepSpinnerValueChanged, true);
            app.AodAxes = uiaxes(app.Visualizer);
            title(app.AodAxes, '')
            xlabel(app.AodAxes, '')
            ylabel(app.AodAxes, '')
            app.AoaAxes = uiaxes(app.Visualizer);
            title(app.AoaAxes, '')
            xlabel(app.AoaAxes, '')
            ylabel(app.AoaAxes, '')
            app.BrowseButton = uibutton(app.Visualizer, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectScenarioLabel = uilabel(app.Visualizer);
            app.SelectScenarioLabel.Text = 'Select Scenario: ';
            app.ReflectionOrderLabel = uilabel(app.Visualizer);
            app.ReflectionOrderLabel.Text = 'Reflection Order';
            app.RefOrderDropdown = uidropdown(app.Visualizer);
            app.RefOrderDropdown.ValueChangedFcn = createCallbackFcn(app, @RefOrderDropdownValueChanged, true);
            % Show the figure after all components are created
            app.Visualizer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Visualizer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Visualizer)
        end
    end
end